classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        StartofAcquisitionButton  matlab.ui.control.StateButton
        GeneralSettingsofthePipelineLabel  matlab.ui.control.Label
        SaveParamsButton          matlab.ui.control.Button
        UseEnvCheckBox            matlab.ui.control.CheckBox
        RunButton                 matlab.ui.control.Button
        ActPrcMG                  matlab.ui.control.NumericEditField
        FuseGaps                  matlab.ui.control.NumericEditField
        EnvWindowMs               matlab.ui.control.NumericEditField
        MinQuiet                  matlab.ui.control.NumericEditField
        QuietThreshold            matlab.ui.control.NumericEditField
        SNRwindow                 matlab.ui.control.NumericEditField
        ActPrcMGLabel             matlab.ui.control.Label
        RecIDDropDown             matlab.ui.control.DropDown
        RecIDLabel                matlab.ui.control.Label
        ActPrcTALabel             matlab.ui.control.Label
        SNRwindowLabel            matlab.ui.control.Label
        FuseGapLabel              matlab.ui.control.Label
        MinQuietLabel             matlab.ui.control.Label
        EnvWindowMsLabel          matlab.ui.control.Label
        ActPrcTA                  matlab.ui.control.NumericEditField
        QuietThresholdsLabel      matlab.ui.control.Label
        OpenFileButton            matlab.ui.control.Button
        FileName                  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        P struct % Parameters for the implementation of the pipeline
        options struct %Figure plotting arguments and other
        availableRecIDs double = []
        TT_clean
        snrValue
        meta
        preview
        isPickingStartTime logical = false

    end
    
    methods (Access = private)
        
        function FigureButtonDown(app, event)
            disp('FigureButtonDown fired');
        
            if ~app.isPickingStartTime
                return;
            end
        
            % Check the click landed inside the axes
            cp = app.UIAxes.CurrentPoint;
            xClick = cp(1, 1);
            yClick = cp(1, 2);
        
            xl = app.UIAxes.XLim;
            yl = app.UIAxes.YLim;
        
            if xClick < xl(1) || xClick > xl(2) || yClick < yl(1) || yClick > yl(2)
                disp('click outside axes bounds, ignoring');
                return;
            end
        
            disp(xClick);
            app.P.startTime = xClick;
        
            app.isPickingStartTime = false;
            app.UIFigure.Pointer = 'arrow';
            app.UIFigure.WindowButtonDownFcn = '';
            app.StartofAcquisitionButton.Value = false;
        
            UpdateMainPlot(app);
        end
        
        function readFieldsIntoP(app)
            app.P.envWindowMs = app.EnvWindowMs.Value;
            app.P.thresholds = app.QuietThreshold.Value;
            app.P.min_quiet_dur_ms = app.MinQuiet.Value;
            app.P.fuse_gap_ms = app.FuseGaps.Value;
            app.P.snr_win_ms = app.SNRwindow.Value;
            app.P.act_prc = app.ActPrcTA.Value;
            app.P.act_prc_MG = app.ActPrcMG.Value;
            app.P.use_envelope = app.UseEnvCheckBox.Value;
           
           
        end
        
        function UpdateMainPlot(app)
            % Clear the axes
            cla(app.UIAxes, 'reset');
            
            % Plot the filtered/envelope signals
            plot_filtered_labeled(app.preview.TA_plot, app.preview.MG_plot, app.preview.Ch3, ...
                    app.preview.t, app.snrValue, app.UIAxes);
            
            % Make all plot children pass clicks through to the axes
            set(app.UIAxes.Children, 'HitTest', 'off', 'PickableParts', 'none');
            
            % CRITICAL: Force white background after plotting
            % plot_filtered_labeled may set black background, so we override here
            set(app.UIAxes, 'Color', 'white');
            set(app.UIAxes, 'XColor', 'black', 'YColor', 'black', 'ZColor', 'black');
            set(app.UIAxes, 'GridColor', [0.2 0.2 0.2]);
            set(app.UIAxes, 'GridAlpha', 0.3);
            grid(app.UIAxes, 'on');
            
            % Set all text properties to black for visibility on white background
            % Get current title, xlabel, ylabel and set their colors to black
            titleHandle = get(app.UIAxes, 'Title');
            if ~isempty(titleHandle)
                set(titleHandle, 'Color', 'black');
            end
            
            xLabelHandle = get(app.UIAxes, 'XLabel');
            if ~isempty(xLabelHandle)
                set(xLabelHandle, 'Color', 'black');
            end
            
            yLabelHandle = get(app.UIAxes, 'YLabel');
            if ~isempty(yLabelHandle)
                set(yLabelHandle, 'Color', 'black');
            end
            
            % Ensure all line colors are visible (not white)
            lines = findall(app.UIAxes, 'Type', 'line');
            for i = 1:length(lines)
                lineColor = get(lines(i), 'Color');
                % If color is white or very light, change to dark
                if isequal(lineColor, [1 1 1]) || (sum(lineColor) > 2.7)
                    % Keep original line colors for data lines, only fix white ones
                    if isequal(lineColor, [1 1 1])
                        set(lines(i), 'Color', 'black');
                    end
                end
            end
            
            % Draw start time marker if defined
            if isfield(app.P, 'startTime') && ~isempty(app.P.startTime)
                hold(app.UIAxes, 'on');
                xline(app.UIAxes, app.P.startTime, ':', ...
                    'Color', [1 0.85 0], ...
                    'LineWidth', 2, ...
                    'Label', sprintf('t_0 = %.3f s', app.P.startTime), ...
                    'LabelHorizontalAlignment', 'right', ...
                    'LabelVerticalAlignment', 'bottom');
                hold(app.UIAxes, 'off');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.P = default_emg_parameters();

            app.options = struct();
            app.options.plot_figures = false;
            app.options.save_figures = false;
            app.options.fig_folder = 'Figures';
            app.options.fullFile = '';
            app.options.recID = NaN;

            app.FileName.Text = 'No file loaded';
            app.EnvWindowMs.Value = app.P.envWindowMs;
            app.QuietThreshold.Value = app.P.thresholds(1);
            app.MinQuiet.Value = app.P.min_quiet_dur_ms;
            app.FuseGaps.Value = app.P.fuse_gap_ms;
            app.SNRwindow.Value = app.P.snr_win_ms;
            app.ActPrcTA.Value = app.P.act_prc;
            app.ActPrcMG.Value = app.P.act_prc_MG;
            app.UseEnvCheckBox.Value = app.P.use_envelope;
            
            % Set white background for entire GUI - must be called AFTER components created
            % This ensures all panels, buttons, and fields have white backgrounds
            set_gui_white_background(app.UIFigure);
            app.RightPanel.AutoResizeChildren = 'off';
            app.LeftPanel.AutoResizeChildren = 'off';


        end

        % Button pushed function: OpenFileButton
        function LoadFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.mat', 'Select experiment MAT file');
            if isequal(fileName, 0)
                return;
            end

            app.options.fullFile = fullfile(filePath, fileName);
            app.FileName.Text = fileName;

            S = load(app.options.fullFile);
            allVars = fieldnames(S);
            recNums = [];
            expr = '^data__chan_1_rec_(\d+)$';

            for i = 1:numel(allVars)
                tok = regexp(allVars{i}, expr, 'tokens');
                if ~isempty(tok)
                    recNums(end+1) = str2double(tok{1}{1}); %#ok<AGROW>
                end
            end

            recNums = unique(recNums);
            app.availableRecIDs = recNums;

            if isempty(recNums)
                app.RecIDDropDown.Items = {};
            else
                app.RecIDDropDown.Items = string(recNums);
                app.RecIDDropDown.Value = string(recNums(1));
                
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                if isempty(app.options.fullFile)
                    app.FileName.Text = 'Please load a file first';
                    return;
                end
        
                % Read current parameter values from GUI fields
                readFieldsIntoP(app);
        
                % Get the selected recording ID from dropdown
                % CRITICAL: Convert to double (not string) so recID is NOT NaN
                recIDStr = app.RecIDDropDown.Value;
                if isempty(recIDStr) || strcmp(recIDStr, '')
                    app.FileName.Text = 'Please select a recording';
                    return;
                end
                app.options.recID = str2double(recIDStr);
                
                app.options.plot_figures = false;
                app.options.save_figures = false;
        
                % Build options struct from app.P and app.options
                % This struct gets passed to preprocess_and_label
                options = struct();
                options.envWindowMs = app.P.envWindowMs;
                options.thresholds = app.P.thresholds;
                options.min_quiet_dur_ms = app.P.min_quiet_dur_ms;
                options.fuse_gap_ms = app.P.fuse_gap_ms;
                options.snr_win_ms = app.P.snr_win_ms;
                options.act_prc = app.P.act_prc;
                options.act_prc_MG = app.P.act_prc_MG;
                options.plot_figures = app.options.plot_figures;
                options.save_figures = app.options.save_figures;
                options.fig_folder = app.options.fig_folder;
                options.use_envelope = app.P.use_envelope;
                options.fullFile = app.options.fullFile;
                options.recID = app.options.recID;  % CRITICAL: Pass the numeric recID
        
                % Call preprocess_and_label with new signature
                % This will NOT prompt for file/recording because recID is provided (not NaN)
                [app.TT_clean, app.snrValue, app.meta] = preprocess_and_label(10000, options);
        
                % Extract preview data for plotting from the processed timetable
                % app.TT_clean is a timetable with columns: TA_env, MG_env, TA_f, MG_f, etc.
                
                app.preview = struct();
                
                if app.P.use_envelope
                    % Display envelope multiplied by sign of original filtered signal
                    % This shows the oscillations of the original signal with smoothed amplitude
                    app.preview.TA_plot = app.TT_clean.TA_env .* sign(app.TT_clean.TA_f);
                    app.preview.MG_plot = app.TT_clean.MG_env .* sign(app.TT_clean.MG_f);
                else
                    % Display the filtered signals directly (without envelope)
                    app.preview.TA_plot = app.TT_clean.TA_f;
                    app.preview.MG_plot = app.TT_clean.MG_f;
                end
                
                app.preview.Ch3 = app.TT_clean.Ch3_raw;
                app.preview.t = seconds(app.TT_clean.tDur);
        
                % Update the plot display
                UpdateMainPlot(app);
                
                app.FileName.Text = sprintf('Ready: %d samples processed', length(app.preview.t));
        
            catch ME
                disp(getReport(ME, 'extended'))
                uialert(app.UIFigure, getReport(ME, 'extended'), 'Processing Error', ...
                    'Interpreter', 'none');
            end
        
        
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
                try
                    readFieldsIntoP(app);
            
                    % Build default save name from source file
                    [srcPath, srcName, ~] = fileparts(app.options.fullFile);
                    defaultName = fullfile(srcPath, [srcName, '_param.mat']);
            
                    [fileName, filePath] = uiputfile('*.mat', 'Save current parameter set', defaultName);
                    if isequal(fileName, 0)
                        return;
                    end
            
                    P = app.P;
                    if ~isempty(app.meta)
                        meta = app.meta;
                        save(fullfile(filePath, fileName), 'P', 'meta');
                    else
                        save(fullfile(filePath, fileName), 'P');
                    end
            
                    if isfield(P, 'startTime') && ~isempty(P.startTime)
                        app.FileName.Text = sprintf('Saved: %s (t0=%.3fs)', fileName, P.startTime);
                    else
                        app.FileName.Text = ['Saved: ', fileName];
                    end
            
                catch ME
                    app.FileName.Text = ['Save error: ', ME.message];
                end
        end

        % Value changed function: StartofAcquisitionButton
        function StartofAcquisitionButtonValueChanged(app, event)
            disp('StartofAcquisition toggled');
            disp(app.StartofAcquisitionButton.Value);
        
            if app.StartofAcquisitionButton.Value
                app.isPickingStartTime = true;
                app.UIFigure.Pointer = 'crosshair';
                % Register on the figure, not the axes
                app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @FigureButtonDown, true);
            else
                app.P.startTime = [];
                app.isPickingStartTime = false;
                app.UIFigure.Pointer = 'arrow';
                app.UIFigure.WindowButtonDownFcn = '';
                if ~isempty(app.preview)
                    UpdateMainPlot(app);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {591, 591};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {259, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.FileName = uilabel(app.GridLayout2);
            app.FileName.Text = 'File Name';
            app.OpenFileButton = uibutton(app.GridLayout2, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File ';
            app.QuietThresholdsLabel = uilabel(app.GridLayout2);
            app.QuietThresholdsLabel.Text = 'Quiet Thresholds';
            app.ActPrcTA = uieditfield(app.GridLayout2, 'numeric');
            app.EnvWindowMsLabel = uilabel(app.GridLayout2);
            app.EnvWindowMsLabel.Text = 'EnvWindowMs    ';
            app.MinQuietLabel = uilabel(app.GridLayout2);
            app.MinQuietLabel.Text = 'Min Quiet';
            app.FuseGapLabel = uilabel(app.GridLayout2);
            app.FuseGapLabel.Text = 'Fuse Gap';
            app.SNRwindowLabel = uilabel(app.GridLayout2);
            app.SNRwindowLabel.Text = 'SNR window';
            app.ActPrcTALabel = uilabel(app.GridLayout2);
            app.ActPrcTALabel.Text = 'ActPrcTA';
            app.RecIDLabel = uilabel(app.GridLayout2);
            app.RecIDLabel.Text = 'RecID         ';
            app.RecIDDropDown = uidropdown(app.GridLayout2);
            app.ActPrcMGLabel = uilabel(app.GridLayout2);
            app.ActPrcMGLabel.Text = 'ActPrcMG';
            app.SNRwindow = uieditfield(app.GridLayout2, 'numeric');
            app.QuietThreshold = uieditfield(app.GridLayout2, 'numeric');
            app.MinQuiet = uieditfield(app.GridLayout2, 'numeric');
            app.EnvWindowMs = uieditfield(app.GridLayout2, 'numeric');
            app.FuseGaps = uieditfield(app.GridLayout2, 'numeric');
            app.ActPrcMG = uieditfield(app.GridLayout2, 'numeric');
            app.RunButton = uibutton(app.GridLayout2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UseEnvCheckBox = uicheckbox(app.GridLayout2);
            app.UseEnvCheckBox.Text = 'Use Env';
            app.SaveParamsButton = uibutton(app.GridLayout2, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.GeneralSettingsofthePipelineLabel = uilabel(app.GridLayout2);
            app.GeneralSettingsofthePipelineLabel.Text = 'General Settings of the Pipeline';
            app.StartofAcquisitionButton = uibutton(app.GridLayout2, 'state');
            app.StartofAcquisitionButton.ValueChangedFcn = createCallbackFcn(app, @StartofAcquisitionButtonValueChanged, true);
            app.StartofAcquisitionButton.Text = 'Start of Acquisition';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end